classdef TS_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        TouchscreenTaskGUILabel  matlab.ui.control.Label
        TrialsEditField          matlab.ui.control.NumericEditField
        TrialsEditFieldLabel     matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        PositionDropDown         matlab.ui.control.DropDown
        PositionDropDownLabel    matlab.ui.control.Label
        RadiusEditField          matlab.ui.control.NumericEditField
        RadiusEditFieldLabel     matlab.ui.control.Label
        ColorDropDown            matlab.ui.control.DropDown
        ColorDropDownLabel       matlab.ui.control.Label
        StartButton              matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            screen_size = get(0,'ScreenSize');
            fig = figure('Position',screen_size);
            set(fig,'Color',[1 1 1]);
            set(gca,'XColor','w','YColor','w');
            fig.MenuBar = 'none';
            fig.DockControls = 'off';
            set(fig, 'Pointer', 'custom', 'PointerShapeCData', NaN(16,16))
            axis equal
            xlim([0 screen_size(3)]);
            ylim([0 screen_size(4)]);
            % initializes figure

            color = app.ColorDropDown.Value; % color of circle
            num = app.TrialsEditField.Value; % number of points
            r = app.RadiusEditField.Value; % radius of circle
            position = app.PositionDropDown.Value; % position of circle

            xco = round(screen_size(3)/2);
            yco = round(screen_size(4)/2);
            mouse = zeros(1,num);
            x = zeros(1,num);
            y = zeros(1,num);

            for i = 1:num
                [mouse(i), x(i), y(i)] = touchscreen(color,r,position);
                pause(1)
            end

            scatter(app.UIAxes,x,y)
            xlim(app.UIAxes,[0 screen_size(3)]);
            ylim(app.UIAxes,[0 screen_size(4)]);

            true = sum(mouse)/size(mouse,2)*100; % percent true
            false = 100 - true; % percent false
            disp(true);

            close(fig)
        end

        % Callback function: not associated with a component
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Callback function: not associated with a component
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            true = sum(mouse)/size(mouse)*100; % percent true
            false = 100 - true; % percent false
            app.UITable.Data = {true false};
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {467, 467};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ColorDropDownLabel = uilabel(app.LeftPanel);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.LeftPanel);
            app.ColorDropDown.Items = {'red', 'blue', 'green'};
            app.ColorDropDown.Value = 'red';
            app.RadiusEditFieldLabel = uilabel(app.LeftPanel);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RadiusEditField.Limits = [0 Inf];
            app.RadiusEditField.Value = 50;
            app.PositionDropDownLabel = uilabel(app.LeftPanel);
            app.PositionDropDownLabel.Text = 'Position';
            app.PositionDropDown = uidropdown(app.LeftPanel);
            app.PositionDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.PositionDropDown.Value = '5';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'grid.png');
            app.TrialsEditFieldLabel = uilabel(app.LeftPanel);
            app.TrialsEditFieldLabel.Text = 'Trials';
            app.TrialsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TrialsEditField.Limits = [1 Inf];
            app.TrialsEditField.Value = 10;
            app.TouchscreenTaskGUILabel = uilabel(app.LeftPanel);
            app.TouchscreenTaskGUILabel.Text = 'Touchscreen Task GUI';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Touch Coordinates')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TS_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end